classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tools1Tab                       matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        LengthEditFieldLabel            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        ReadDataFromExcelSheetButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Tool2Tab                        matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        ReadDataFromExcelSheetButton_3  matlab.ui.control.Button
        UITable_4                       matlab.ui.control.Table
        Oh2Button                       matlab.ui.control.Button
        Oh4Button                       matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        Tool3Tab_2                      matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        ReadDataFromExcelSheetButton_2  matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        Tool4Tab                        matlab.ui.container.Tab
        DiameterEditFieldLabel          matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        InitialVelocityEditFieldLabel   matlab.ui.control.Label
        InitialVelocityEditField        matlab.ui.control.NumericEditField
        InitialAngleEditFieldLabel      matlab.ui.control.Label
        InitialAngleEditField           matlab.ui.control.NumericEditField
        MassEditField_3Label            matlab.ui.control.Label
        MassEditField_3                 matlab.ui.control.NumericEditField
        FinalPositionEditFieldLabel     matlab.ui.control.Label
        FinalPositionEditField          matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        PlotthingsButton                matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        Tool5Tab                        matlab.ui.container.Tab
        TargetxpositionEditFieldLabel   matlab.ui.control.Label
        TargetxpositionEditField        matlab.ui.control.NumericEditField
        TargetzpositionEditFieldLabel   matlab.ui.control.Label
        TargetzpositionEditField        matlab.ui.control.NumericEditField
        DiameterEditField_2Label        matlab.ui.control.Label
        DiameterEditField_2             matlab.ui.control.NumericEditField
        MassEditField_4Label            matlab.ui.control.Label
        MassEditField_4                 matlab.ui.control.NumericEditField
        UnknownvelocityorangleDropDownLabel  matlab.ui.control.Label
        UnknownvelocityorangleDropDown  matlab.ui.control.DropDown
        ICEditFieldLabel                matlab.ui.control.Label
        ICEditField                     matlab.ui.control.NumericEditField
        DoMagicButton                   matlab.ui.control.Button
        InitialVelocityEditField_2Label  matlab.ui.control.Label
        InitialVelocityEditField_2      matlab.ui.control.NumericEditField
        InitialAngleEditField_2Label    matlab.ui.control.Label
        InitialAngleEditField_2         matlab.ui.control.NumericEditField
        TotalLengthEditFieldLabel       matlab.ui.control.Label
        TotalLengthEditField            matlab.ui.control.NumericEditField
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        v0
        angle0
        xn
        h 
        diam
        mass
        ic
        target_x
        target_y 
        d
        m 
        unknown
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = {'','',''};
            app.UITable.Data=data;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            disp(newData);
            
            x = app.UITable.Data.(1);
            y = app.UITable.Data.(2);
            
            total_length = tool1(x,y);
            
            app.LengthEditField.Value = total_length;
            plot(app.UIAxes, x,y);
            %app.LengthEditField.Value = str2double(newData);


        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            nrows = size(app.UITable.Data,1);
            %ncols = size(app.UITable.Data,2);
            
%             if indices(1, 1) == nrows
%                 app.UITable.Data(nrows+1, :) = {0,0,0};
%             end
            
        end

        % Button pushed function: ReadDataFromExcelSheetButton
        function ReadDataFromExcelSheetButtonPushed(app, event)
            t = readtable("example_values.xlsx");
            app.UITable.Data = t;
            
            x = t.(1);
            y = t.(2);
           
            total_length = tool1(x,y);
            app.LengthEditField.Value = total_length;
            plot(app.UIAxes, x,y);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Button pushed function: ReadDataFromExcelSheetButton_3
        function ReadDataFromExcelSheetButton_3Pushed(app, event)
            t = readtable("example_values.xlsx");
            app.UITable_3.Data = t;
        end

        % Button pushed function: Oh2Button
        function Oh2ButtonPushed(app, event)
            t = app.UITable_3.Data.(1);
            x = app.UITable_3.Data.(2);
            z = app.UITable_3.Data.(3);
            
            v_x = second_ord_centered_difference(x, t);
            v_z = second_ord_centered_difference(z, t);
            resultant_v = sqrt(v_x.^2 + v_z.^2);
            angle = atan(v_z ./ v_x);
            
            app.UITable_4.Data(:,1) = t;
            app.UITable_4.Data(:,2) = resultant_v;
            app.UITable_4.Data(:,3) = angle;
            
            plot(app.UIAxes2, t, resultant_v);
            plot(app.UIAxes2_2, t, angle);
        end

        % Button pushed function: Oh4Button
        function Oh4ButtonPushed(app, event)
            t = app.UITable_3.Data.(1);
            x = app.UITable_3.Data.(2);
            z = app.UITable_3.Data.(3);
            
            v_x = fourth_ord_centered_difference(x, t);
            v_z = fourth_ord_centered_difference(z, t);
            resultant_v = sqrt(v_x.^2 + v_z.^2);
            angle = atan(v_z ./ v_x);
            
            app.UITable_4.Data(:,1) = t;
            app.UITable_4.Data(:,2) = resultant_v;
            app.UITable_4.Data(:,3) = angle;
            
            plot(app.UIAxes2, t, resultant_v);
            plot(app.UIAxes2_2, t, angle);
        end

        % Button pushed function: ReadDataFromExcelSheetButton_2
        function ReadDataFromExcelSheetButton_2Pushed(app, event)
            tt = readtable("example_values.xlsx");
            app.UITable_2.Data = tt;
            
            t = tt.(1);
            vx = tt.(2);
            vz = tt.(3);
            L = length(t);
            
            [y1, y2] = tool3(t, vx, vz);
            
            xa = 1:1:L-1;
            
            plot(app.UIAxes_2, xa,y1, xa,y2);
        end

        % Value changed function: InitialVelocityEditField
        function InitialVelocityEditFieldValueChanged(app, event)
            app.v0 = app.InitialVelocityEditField.Value;
            
        end

        % Value changed function: InitialAngleEditField
        function InitialAngleEditFieldValueChanged(app, event)
            app.angle0 = app.InitialAngleEditField.Value;
            
        end

        % Value changed function: FinalPositionEditField
        function FinalPositionEditFieldValueChanged(app, event)
            app.xn = app.FinalPositionEditField.Value;
            
        end

        % Value changed function: StepsizeEditField
        function StepsizeEditFieldValueChanged(app, event)
            app.h = app.StepsizeEditField.Value;
            
        end

        % Value changed function: MassEditField_3
        function MassEditField_3ValueChanged(app, event)
            app.mass = app.MassEditField_3.Value;
            
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.diam = app.DiameterEditField.Value;
            
        end

        % Button pushed function: PlotthingsButton
        function PlotthingsButtonPushed(app, event)
            [resultant_velocity, all_angles] = tool4(0,app.v0, app.angle0, app.xn, app.h, app.diam, app.mass, 0);
            
            t = 0:app.h:app.xn;
            plot(app.UIAxes_3, t, resultant_velocity);
            
            plot(app.UIAxes_6, t, all_angles);
        end

        % Value changed function: UnknownvelocityorangleDropDown
        function UnknownvelocityorangleDropDownValueChanged(app, event)
            app.unknown = app.UnknownvelocityorangleDropDown.Value;
            
        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            
        end

        % Value changed function: ICEditField
        function ICEditFieldValueChanged(app, event)
            app.ic = app.ICEditField.Value;
            
        end

        % Value changed function: TargetxpositionEditField
        function TargetxpositionEditFieldValueChanged(app, event)
            app.target_x = app.TargetxpositionEditField.Value;
            
        end

        % Value changed function: TargetzpositionEditField
        function TargetzpositionEditFieldValueChanged(app, event)
            app.target_y = app.TargetzpositionEditField.Value;
            
        end

        % Value changed function: DiameterEditField_2
        function DiameterEditField_2ValueChanged(app, event)
            app.d = app.DiameterEditField_2.Value;
            
        end

        % Value changed function: MassEditField_4
        function MassEditField_4ValueChanged(app, event)
            app.m = app.MassEditField_4.Value;
            
        end

        % Button pushed function: DoMagicButton
        function DoMagicButtonPushed(app, event)
            [velocity_or_angle, resultant_velocity, x,y,all_angles, total_length] = tool5(app.target_x, app.target_y, app.ic, app.d, app.m, app.unknown);
            
            
            app.TotalLengthEditField.Value = total_length;
            
            if strcmp(app.unknown, 'angle')
                app.InitialVelocityEditField_2.Value = app.ic;
                app.InitialAngleEditField_2.Value = velocity_or_angle;
                
            else
                app.InitialVelocityEditField_2.Value = velocity_or_angle;
                app.InitialAngleEditField_2.Value = app.ic;
            end
            
            tmax = 5;
            
            plot(app.UIAxes_7, x, y);
            tspan = linspace(0,tmax, length(resultant_velocity));
            plot(app.UIAxes_5, tspan, resultant_velocity);
            tspan = linspace(0,tmax, length(all_angles));
            plot(app.UIAxes_4, tspan, all_angles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tools1Tab = uitab(app.TabGroup);
            app.Tools1Tab.Title = 'Tools 1';
            app.UITable = uitable(app.Tools1Tab);
            app.UITable.ColumnName = {'t'; 'x(m)'; 'z(m)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.LengthEditFieldLabel = uilabel(app.Tools1Tab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.Tools1Tab, 'numeric');
            app.LengthEditField.ValueDisplayFormat = '%.2f';
            app.ReadDataFromExcelSheetButton = uibutton(app.Tools1Tab, 'push');
            app.ReadDataFromExcelSheetButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataFromExcelSheetButtonPushed, true);
            app.ReadDataFromExcelSheetButton.Text = 'Read Data From Excel Sheet';
            app.UIAxes = uiaxes(app.Tools1Tab);
            title(app.UIAxes, 'Position vs Time')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'v')
            zlabel(app.UIAxes, 'Z')
            app.Tool2Tab = uitab(app.TabGroup);
            app.Tool2Tab.Title = 'Tool 2';
            app.UITable_3 = uitable(app.Tool2Tab);
            app.UITable_3.ColumnName = {'t'; 'x(m)'; 'z(m)'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = true;
            app.ReadDataFromExcelSheetButton_3 = uibutton(app.Tool2Tab, 'push');
            app.ReadDataFromExcelSheetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReadDataFromExcelSheetButton_3Pushed, true);
            app.ReadDataFromExcelSheetButton_3.Text = 'Read Data From Excel Sheet';
            app.UITable_4 = uitable(app.Tool2Tab);
            app.UITable_4.ColumnName = {'t'; 'Resultant Velocity'; 'Angle'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = true;
            app.Oh2Button = uibutton(app.Tool2Tab, 'push');
            app.Oh2Button.ButtonPushedFcn = createCallbackFcn(app, @Oh2ButtonPushed, true);
            app.Oh2Button.Text = 'O(h2)';
            app.Oh4Button = uibutton(app.Tool2Tab, 'push');
            app.Oh4Button.ButtonPushedFcn = createCallbackFcn(app, @Oh4ButtonPushed, true);
            app.Oh4Button.Text = 'O(h4)';
            app.UIAxes2 = uiaxes(app.Tool2Tab);
            title(app.UIAxes2, 'Time vs Resultant Velocity')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'v')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Tool2Tab);
            title(app.UIAxes2_2, 'Time vs Angle')
            xlabel(app.UIAxes2_2, 't')
            ylabel(app.UIAxes2_2, 'angle')
            zlabel(app.UIAxes2_2, 'Z')
            app.Tool3Tab_2 = uitab(app.TabGroup);
            app.Tool3Tab_2.Title = 'Tool 3';
            app.UITable_2 = uitable(app.Tool3Tab_2);
            app.UITable_2.ColumnName = {'t(s)'; 'Vx(m/s)'; 'Vy(m/s)'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.ReadDataFromExcelSheetButton_2 = uibutton(app.Tool3Tab_2, 'push');
            app.ReadDataFromExcelSheetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReadDataFromExcelSheetButton_2Pushed, true);
            app.ReadDataFromExcelSheetButton_2.Text = 'Read Data From Excel Sheet';
            app.UIAxes_2 = uiaxes(app.Tool3Tab_2);
            title(app.UIAxes_2, 'Position (x,y) vs time interval')
            xlabel(app.UIAxes_2, 'Time Interval')
            ylabel(app.UIAxes_2, 'Position(m)')
            zlabel(app.UIAxes_2, 'Z')
            app.Tool4Tab = uitab(app.TabGroup);
            app.Tool4Tab.Title = 'Tool 4';
            app.DiameterEditFieldLabel = uilabel(app.Tool4Tab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.Tool4Tab, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.InitialVelocityEditFieldLabel = uilabel(app.Tool4Tab);
            app.InitialVelocityEditFieldLabel.Text = 'Initial Velocity';
            app.InitialVelocityEditField = uieditfield(app.Tool4Tab, 'numeric');
            app.InitialVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @InitialVelocityEditFieldValueChanged, true);
            app.InitialAngleEditFieldLabel = uilabel(app.Tool4Tab);
            app.InitialAngleEditFieldLabel.Text = 'Initial Angle';
            app.InitialAngleEditField = uieditfield(app.Tool4Tab, 'numeric');
            app.InitialAngleEditField.ValueChangedFcn = createCallbackFcn(app, @InitialAngleEditFieldValueChanged, true);
            app.MassEditField_3Label = uilabel(app.Tool4Tab);
            app.MassEditField_3Label.Text = 'Mass';
            app.MassEditField_3 = uieditfield(app.Tool4Tab, 'numeric');
            app.MassEditField_3.ValueChangedFcn = createCallbackFcn(app, @MassEditField_3ValueChanged, true);
            app.FinalPositionEditFieldLabel = uilabel(app.Tool4Tab);
            app.FinalPositionEditFieldLabel.Text = 'Final Position';
            app.FinalPositionEditField = uieditfield(app.Tool4Tab, 'numeric');
            app.FinalPositionEditField.ValueChangedFcn = createCallbackFcn(app, @FinalPositionEditFieldValueChanged, true);
            app.StepsizeEditFieldLabel = uilabel(app.Tool4Tab);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.Tool4Tab, 'numeric');
            app.StepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeEditFieldValueChanged, true);
            app.PlotthingsButton = uibutton(app.Tool4Tab, 'push');
            app.PlotthingsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotthingsButtonPushed, true);
            app.PlotthingsButton.Text = 'Plot things!';
            app.UIAxes_3 = uiaxes(app.Tool4Tab);
            title(app.UIAxes_3, 'Resultant velocity vs time')
            xlabel(app.UIAxes_3, 't')
            ylabel(app.UIAxes_3, 'v')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_6 = uiaxes(app.Tool4Tab);
            title(app.UIAxes_6, 'Angle vs time')
            xlabel(app.UIAxes_6, 't')
            ylabel(app.UIAxes_6, 'v')
            zlabel(app.UIAxes_6, 'Z')
            app.Tool5Tab = uitab(app.TabGroup);
            app.Tool5Tab.Title = 'Tool 5';
            app.TargetxpositionEditFieldLabel = uilabel(app.Tool5Tab);
            app.TargetxpositionEditFieldLabel.Text = 'Target x position';
            app.TargetxpositionEditField = uieditfield(app.Tool5Tab, 'numeric');
            app.TargetxpositionEditField.ValueChangedFcn = createCallbackFcn(app, @TargetxpositionEditFieldValueChanged, true);
            app.TargetzpositionEditFieldLabel = uilabel(app.Tool5Tab);
            app.TargetzpositionEditFieldLabel.Text = 'Target z position';
            app.TargetzpositionEditField = uieditfield(app.Tool5Tab, 'numeric');
            app.TargetzpositionEditField.ValueChangedFcn = createCallbackFcn(app, @TargetzpositionEditFieldValueChanged, true);
            app.DiameterEditField_2Label = uilabel(app.Tool5Tab);
            app.DiameterEditField_2Label.Text = 'Diameter';
            app.DiameterEditField_2 = uieditfield(app.Tool5Tab, 'numeric');
            app.DiameterEditField_2.ValueChangedFcn = createCallbackFcn(app, @DiameterEditField_2ValueChanged, true);
            app.MassEditField_4Label = uilabel(app.Tool5Tab);
            app.MassEditField_4Label.Text = 'Mass';
            app.MassEditField_4 = uieditfield(app.Tool5Tab, 'numeric');
            app.MassEditField_4.ValueChangedFcn = createCallbackFcn(app, @MassEditField_4ValueChanged, true);
            app.UnknownvelocityorangleDropDownLabel = uilabel(app.Tool5Tab);
            app.UnknownvelocityorangleDropDownLabel.Text = 'Unknown velocity or angle?';
            app.UnknownvelocityorangleDropDown = uidropdown(app.Tool5Tab);
            app.UnknownvelocityorangleDropDown.Items = {'velocity', 'angle'};
            app.UnknownvelocityorangleDropDown.ValueChangedFcn = createCallbackFcn(app, @UnknownvelocityorangleDropDownValueChanged, true);
            app.UnknownvelocityorangleDropDown.Value = 'velocity';
            app.ICEditFieldLabel = uilabel(app.Tool5Tab);
            app.ICEditFieldLabel.Text = 'IC';
            app.ICEditField = uieditfield(app.Tool5Tab, 'numeric');
            app.ICEditField.ValueChangedFcn = createCallbackFcn(app, @ICEditFieldValueChanged, true);
            app.DoMagicButton = uibutton(app.Tool5Tab, 'push');
            app.DoMagicButton.ButtonPushedFcn = createCallbackFcn(app, @DoMagicButtonPushed, true);
            app.DoMagicButton.Text = 'Do Magic!';
            app.InitialVelocityEditField_2Label = uilabel(app.Tool5Tab);
            app.InitialVelocityEditField_2Label.Text = 'Initial Velocity';
            app.InitialVelocityEditField_2 = uieditfield(app.Tool5Tab, 'numeric');
            app.InitialAngleEditField_2Label = uilabel(app.Tool5Tab);
            app.InitialAngleEditField_2Label.Text = 'Initial Angle';
            app.InitialAngleEditField_2 = uieditfield(app.Tool5Tab, 'numeric');
            app.TotalLengthEditFieldLabel = uilabel(app.Tool5Tab);
            app.TotalLengthEditFieldLabel.Text = 'Total Length';
            app.TotalLengthEditField = uieditfield(app.Tool5Tab, 'numeric');
            app.UIAxes_4 = uiaxes(app.Tool5Tab);
            title(app.UIAxes_4, 'Angle vs time')
            xlabel(app.UIAxes_4, 't')
            ylabel(app.UIAxes_4, 'x')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.Tool5Tab);
            title(app.UIAxes_5, 'Resultant velocity vs time')
            xlabel(app.UIAxes_5, 't')
            ylabel(app.UIAxes_5, 'v')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.UIAxes_7 = uiaxes(app.Tool5Tab);
            title(app.UIAxes_7, 'Position vs time')
            xlabel(app.UIAxes_7, 't')
            ylabel(app.UIAxes_7, 'v')
            zlabel(app.UIAxes_7, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end